classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CopyrightDetector         matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        DataPreparationTab        matlab.ui.container.Tab
        TestingExcerptPanel       matlab.ui.container.Panel
        LoadButton_2              matlab.ui.control.Button
        GenerateButton_2          matlab.ui.control.Button
        DataViewerPanel           matlab.ui.container.Panel
        filepathEditField         matlab.ui.control.EditField
        filepathEditFieldLabel    matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        AudioLibraryPanel         matlab.ui.container.Panel
        GenerateButton            matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        TestingTab                matlab.ui.container.Tab
        ConfusionMatrixPanel      matlab.ui.container.Panel
        TrueClassLabel            matlab.ui.control.Label
        PredictedClassLabel       matlab.ui.control.Label
        UITable2                  matlab.ui.control.Table
        ResultPanel               matlab.ui.container.Panel
        DescriptionTextArea       matlab.ui.control.TextArea
        DescriptionTextAreaLabel  matlab.ui.control.Label
        WatingLabel               matlab.ui.control.Label
        WaitingLamp               matlab.ui.control.Lamp
        StartPanel                matlab.ui.container.Panel
        trainandpredictButton     matlab.ui.control.Button
        LoadDataPanel             matlab.ui.container.Panel
        TrainingDataButton        matlab.ui.control.Button
        TestingDataButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        tableBPM
        trainT
        testT
        tempogramToolboxPath = 'C:\Users\paowen\Documents\111-2\Piano_Comparison\code\MATLAB-Tempogram-Toolbox_1.0';
        labels = {'ANDRAS'; 'DANIEL'; 'GLENN'; 'MARIA'; 'MITSUKO'; 'ROLAND'};
        batch_size = 5;
        test_fullfilepath;
        midBPM;
    end
    
    methods (Access = private)
        
        function Feature_for_train = fperformanceAnoSplit(app, filename, artistName, batch_size)
            [x,Fs] = audioread(filename); % [str1, str2] = str1 + str2
            x = mean(x, 2);     % 若樂曲是雙聲道，轉為單聲道
            x = x(x ~= 0);      % remove 0 in input signal
            
            % x actual length (unit: second)
            % Length_x = size(x,1)/Fs;
            
            desiredFs = 22050;
            [p, q] = rat(desiredFs / Fs);
            
            x = resample(x,p,q);
            Fs = 22050;
            
            lowestBPM = 30;                 % 最低偵測BPM
            highestBPM = 600;               % 最高偵測BPM
            BPM = lowestBPM:1:highestBPM;   % 偵測BPM的範圍
            
            pointFs = 2.5;
            % batch_size = 0; % how many points, 0 and 1 are equivalent
            
            
            %% 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % generate noveltyCurve
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            parameterNovelty = [];
            
            [noveltyCurve,featureRate] = audio_to_noveltyCurve(x, Fs, parameterNovelty);
            
            parameterVis = [];
            parameterVis.featureRate = featureRate;
            
            % visualize_noveltyCurve(noveltyCurve,parameterVis)
            % title('Novelty curve')
            
            %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Fourier tempogram
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            parameterTempogram = [];
            parameterTempogram.featureRate = featureRate;
            parameterTempogram.tempoWindow = 6;         % window length in sec
            parameterTempogram.BPM = BPM;               % tempo values
            parameterTempogram.stepsize = ceil(parameterTempogram.featureRate./pointFs); % 2.5Hz (一秒的音樂會產生十個points)
            
            [tempogram, T, BPM] = noveltyCurve_to_tempogram_via_DFT(noveltyCurve,parameterTempogram);
            tempogram = normalizeFeature(tempogram,2, 0.0001);
            
            % visualize_tempogram(tempogram,T,BPM)
            % title('Tempogram (Fourier)')
            
            
            %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % compute resultdB and resultBPM 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Mag = abs(tempogram);
            energy_each_band = sum(Mag, 2);
            [~, I] = max(energy_each_band);
            midTempo = I + lowestBPM - 1
            app.midBPM = midTempo;
            
            % 1. 讓程式找範圍
            % rangeBPM = 50;  % 可調整
            % idx = find(BPM >= midTempo - rangeBPM & BPM <= midTempo + rangeBPM); % midTempo +- rangeBPM
            % desiredTempogram = tempogram(idx, :); % 裁切過的 tempogram
            % [~, I] = max(desiredTempogram);
            % resultBPM = I + midTempo - rangeBPM - 1;
            
            % 2. no limit
            [~, I] = max(tempogram);
            resultBPM = I + lowestBPM -1;
            
            tempoInterval = T(2);
            step = tempoInterval * Fs;
            % meanMag = mean(abs(x));
            resultdB = [];
            for st=1:step:length(x)
                ed = st + step - 1;
                if ed > length(x)
                    ed = length(x);
                end
                idxAMP = floor(st):floor(ed);
                meanMag = mean(abs(x(idxAMP)));         % convert AMP to Mag (by abs), and culculate mean value of given time
                resultdB = [resultdB, meanMag];
                % resultdB = [resultdB, mag2db(meanMag)]; % convert Mag to dB, and append to result vector
            end
            
            % fix when length of AMP and BPM not match
            if length(resultdB) < length(resultBPM)
                resultBPM = resultBPM(:, 1:length(resultdB));
                T = T(:, 1:length(resultdB));
            elseif length(resultdB) > length(resultBPM)
                resultdB = resultdB(:, 1:length(resultBPM));
                T = T(:, 1:length(resultBPM));
            end
            
            % data smoothing
            sigma = 2;
            resultBPM = imgaussfilt(resultBPM, sigma);
            resultdB = imgaussfilt(resultdB, sigma);
            
            % normAMP = (resultdB - min(resultdB)) / (max(resultdB) - min(resultdB));
            
            resultBPM=transpose(resultBPM);
            resultdB=transpose(resultdB);
            T=transpose(T);
            
            Label = repmat(string(artistName), 1, length(resultdB));
            Label = transpose(Label);
            Output_Table = table(resultBPM, resultdB, T, Label);
            
            % Feature_generage(window_size, raw_data)
            Feature_for_train = feature_generate(16, Output_Table);
            
                function Feature_for_train = feature_generate(window_size, raw_data)
            
            
                % window_size = 16; 
                % raw_data = readtable('Prelude1.csv');
            
                % B for BPM, L for loudness, T for time series
                B = raw_data.resultBPM;
                B_length = size(B, 1);
                L = raw_data.resultdB;
                L_length = size(L, 1);
                T = raw_data.T;
                Label = raw_data.Label;
                dT = T(2)-T(1);
                
                % before separate point, training data; after, testing data;
                % separate_point = ceil(0.7 * B_length);
                
                B_STD = std(B);
                B_AVG = mean(B);
                L_STD = std(L);
                L_AVG = mean(L);
            
                %% generate avg, range, std, cor, dir, deriva
                % Average, St. Dev., Range
                B_avg = [];
                B_std = [];
                B_range = [];
                L_avg = [];
                L_std = [];
                L_range = [];
            
                % Normalization , in this function std is the Z score calculated from formula (X-U)/std 
                b = (B-B_AVG)./B_STD;
                b_std = []; 
                b_range = [];
                l = (L-L_AVG)./L_STD;
                l_std = [];
                l_range = [];
            
                % Correlation, Directness
                B_T_cor = []; 
                B_T_dir = []; % directness
                L_T_cor = []; 
                L_T_dir = [];
            
                % Derivative
                B_Md = [];
                B_ud = [];
                b_Md = [];
                b_ud = [];
                L_Md = [];
                L_ud = [];
                l_Md = [];
                l_ud = [];
            
                % Others
                B_L_cor = [];
                B_L_dir = [];
            
                %% new code
                for midPtr = 1:B_length % midPtr 指向 window 正中間的數值 (data)
            
                    % normal situation (st, ed 分別代表 window 的頭與尾)
                    st = midPtr - (window_size / 2);
                    ed = midPtr + (window_size / 2);
            
                    if st <= 0              % 處理頭部，window 前段超出資料範圍
                        st = 1;
                    elseif ed > B_length    % 處理尾部，window 後段超出資料範圍
                        ed = B_length;
                    end
                    
                    %% B 
                    B_avg = cat( 1, B_avg, mean( B(st:ed) ) );
                    B_std = cat( 1, B_std, std( B(st:ed) ) );
                    B_range = cat( 1, B_range, range( B(st:ed) ) );
            
                    b_std = cat( 1, b_std, std( b(st:ed) ) );
                    b_range = cat( 1, b_range, range( b(st:ed) ) );
            
                    tmp_for_cor = corrcoef( T(st:ed), B(st:ed) );   % 2-by-2 matrix
                    B_T_cor = cat( 1, B_T_cor, tmp_for_cor(2)  );
                    B_T_dir = cat( 1, B_T_dir, pdist( [B(st),T(st);B(ed),T(ed)] ) / trajectory_length( cat(2, B(st:ed), T(st:ed))) );
            
                    B_Md = cat( 1, B_Md, max( abs( diff( B( st:ed ) ) ) ) / dT );
                    B_ud = cat( 1, B_ud, mean( abs( diff( B( st:ed ) ) ) ) / dT );
                    b_Md = cat( 1, b_Md, max( abs( diff( b( st:ed ) ) ) ) / dT );
                    b_ud = cat( 1, b_ud, mean( abs( diff( b( st:ed ) ) ) ) / dT );
            
                    %% L
                    L_avg = cat( 1, L_avg, mean( L( st:ed ) ) );
                    L_std = cat( 1, L_std, std( L( st:ed ) ) );
                    L_range = cat( 1, L_range, range( L( st:ed ) ) );
            
                    l_std = cat( 1, l_std, std( l(st:ed) ) );
                    l_range = cat( 1, l_range, range( l( st:ed ) ) );
            
                    tmp_for_cor = corrcoef( T(st:ed), L(st:ed) );
                    L_T_cor = cat( 1, L_T_cor, tmp_for_cor(2)  );
                    L_T_dir = cat( 1, L_T_dir, pdist( [L(st),T(st);L(ed),T(ed)] ) / trajectory_length(cat(2, L(st:ed), T(st:ed))) );
            
                    L_Md = cat( 1, L_Md, max( abs( diff( L( st:ed ) ) ) )/dT );
                    L_ud = cat( 1, L_ud, mean( abs( diff( L( st:ed ) ) ) )/dT );
                    l_Md = cat( 1, l_Md, max( abs( diff( l( st:ed ) ) ) )/dT );
                    l_ud = cat( 1, l_ud, mean( abs( diff( l( st:ed ) ) ) )/dT );
            
                    %%   BL
                    tmp_for_cor = corrcoef( B(st:ed), L(st:ed) );
                    B_L_cor = cat( 1, B_L_cor, tmp_for_cor(2) );
                    B_L_dir = cat( 1, B_L_dir, pdist( [B(st),L(st);B(ed),L(ed)] )/trajectory_length( cat(2, B(st:ed),L(st:ed) ) ) );
            
                end
            
                if batch_size == 0
                    Feature_for_train = table(B,B_avg,B_std,B_range,b,b_std,b_range,B_T_cor,B_T_dir,B_Md,B_ud,b_Md,b_ud,l,l_std,l_range,L_T_cor,L_T_dir,l_Md,l_ud,B_L_cor,B_L_dir,T,Label);
                else
                    Feature_for_train = table();
                    data_len = length(B)-window_size*(batch_size-1);
                    T = T(1:data_len);
                    Label = Label(1:data_len);
            
                    st = 1;
                    for i = (1:batch_size)
                        r = st:st+data_len-1;
                        Feature_for_train = addvars(Feature_for_train,B(r),B_avg(r),B_std(r),B_range(r),b(r),b_std(r),b_range(r),B_T_cor(r),B_T_dir(r),B_Md(r),B_ud(r),b_Md(r),b_ud(r),l(r),l_std(r),l_range(r),L_T_cor(r),L_T_dir(r),l_Md(r),l_ud(r),B_L_cor(r),B_L_dir(r));
                        st = st+window_size;
                    end
                    Feature_for_train = addvars(Feature_for_train,T,Label);
                end
            
                % Feature_for_train = table(B,B_avg,B_std,B_range,b,b_std,b_range,B_T_cor,B_T_dir,B_Md,B_ud,b_Md,b_ud,l,l_std,l_range,L_T_cor,L_T_dir,l_Md,l_ud,B_L_cor,B_L_dir,T,Label);
            
                    function length = trajectory_length(A)
                        sz_A = size(A);
                        sz_A = sz_A(1);
                        length = 0;
                        for i=1:sz_A-1
                            length = length + pdist( [ A(i,1),A(i,2);A(i+1,1),A(i+1,2) ] );
                        end
                    end
                end
            end
        
        function [trainedClassifier, validationAccuracy] = autoKNNnolimit(app, trainingData)

            % Extract predictors and response
            % This code processes the data into the right shape for training the
            % model.
            inputTable = trainingData;
            predictorNames = {'Var1', 'Var2', 'Var3', 'Var4', 'Var5', 'Var6', 'Var7', 'Var8', 'Var9', 'Var10', 'Var11', 'Var12', 'Var13', 'Var14', 'Var15', 'Var16', 'Var17', 'Var18', 'Var19', 'Var20', 'Var21', 'Var22', 'Var23', 'Var24', 'Var25', 'Var26', 'Var27', 'Var28', 'Var29', 'Var30', 'Var31', 'Var32', 'Var33', 'Var34', 'Var35', 'Var36', 'Var37', 'Var38', 'Var39', 'Var40', 'Var41', 'Var42', 'Var43', 'Var44', 'Var45', 'Var46', 'Var47', 'Var48', 'Var49', 'Var50', 'Var51', 'Var52', 'Var53', 'Var54', 'Var55', 'Var56', 'Var57', 'Var58', 'Var59', 'Var60', 'Var61', 'Var62', 'Var63', 'Var64', 'Var65', 'Var66', 'Var67', 'Var68', 'Var69', 'Var70', 'Var71', 'Var72', 'Var73', 'Var74', 'Var75', 'Var76', 'Var77', 'Var78', 'Var79', 'Var80', 'Var81', 'Var82', 'Var83', 'Var84', 'Var85', 'Var86', 'Var87', 'Var88', 'Var89', 'Var90', 'Var91', 'Var92', 'Var93', 'Var94', 'Var95', 'Var96', 'Var97', 'Var98', 'Var99', 'Var100', 'Var101', 'Var102', 'Var103', 'Var104', 'Var105', 'Var106', 'Var107', 'Var108', 'Var109', 'Var110', 'T'};
            predictors = inputTable(:, predictorNames);
            response = inputTable.Label;
            isCategoricalPredictor = [false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false];
            classNames = categorical({'ANDRAS'; 'DANIEL'; 'GLENN'; 'MARIA'; 'MITSUKO'; 'ROLAND'});
            
            % Train a classifier
            % This code specifies all the classifier options and trains the classifier.
            classificationKNN = fitcknn(...
                predictors, ...
                response, ...
                'Distance', 'Euclidean', ...
                'Exponent', [], ...
                'NumNeighbors', 1, ...
                'DistanceWeight', 'Equal', ...
                'Standardize', true, ...
                'ClassNames', classNames);
            
            % Create the result struct with predict function
            predictorExtractionFcn = @(t) t(:, predictorNames);
            knnPredictFcn = @(x) predict(classificationKNN, x);
            trainedClassifier.predictFcn = @(x) knnPredictFcn(predictorExtractionFcn(x));
            
            % Add additional fields to the result struct
            trainedClassifier.RequiredVariables = {'T', 'Var1', 'Var10', 'Var100', 'Var101', 'Var102', 'Var103', 'Var104', 'Var105', 'Var106', 'Var107', 'Var108', 'Var109', 'Var11', 'Var110', 'Var12', 'Var13', 'Var14', 'Var15', 'Var16', 'Var17', 'Var18', 'Var19', 'Var2', 'Var20', 'Var21', 'Var22', 'Var23', 'Var24', 'Var25', 'Var26', 'Var27', 'Var28', 'Var29', 'Var3', 'Var30', 'Var31', 'Var32', 'Var33', 'Var34', 'Var35', 'Var36', 'Var37', 'Var38', 'Var39', 'Var4', 'Var40', 'Var41', 'Var42', 'Var43', 'Var44', 'Var45', 'Var46', 'Var47', 'Var48', 'Var49', 'Var5', 'Var50', 'Var51', 'Var52', 'Var53', 'Var54', 'Var55', 'Var56', 'Var57', 'Var58', 'Var59', 'Var6', 'Var60', 'Var61', 'Var62', 'Var63', 'Var64', 'Var65', 'Var66', 'Var67', 'Var68', 'Var69', 'Var7', 'Var70', 'Var71', 'Var72', 'Var73', 'Var74', 'Var75', 'Var76', 'Var77', 'Var78', 'Var79', 'Var8', 'Var80', 'Var81', 'Var82', 'Var83', 'Var84', 'Var85', 'Var86', 'Var87', 'Var88', 'Var89', 'Var9', 'Var90', 'Var91', 'Var92', 'Var93', 'Var94', 'Var95', 'Var96', 'Var97', 'Var98', 'Var99'};
            trainedClassifier.ClassificationKNN = classificationKNN;
            trainedClassifier.About = 'This struct is a trained model exported from Classification Learner R2023a.';
            trainedClassifier.HowToPredict = sprintf('To make predictions on a new table, T, use: \n  [yfit,scores] = c.predictFcn(T) \nreplacing ''c'' with the name of the variable that is this struct, e.g. ''trainedModel''. \n \nThe table, T, must contain the variables returned by: \n  c.RequiredVariables \nVariable formats (e.g. matrix/vector, datatype) must match the original training data. \nAdditional variables are ignored. \n \nFor more information, see <a href="matlab:helpview(fullfile(docroot, ''stats'', ''stats.map''), ''appclassification_exportmodeltoworkspace'')">How to predict using an exported model</a>.');
            
            % Extract predictors and response
            % This code processes the data into the right shape for training the
            % model.
            inputTable = trainingData;
            predictorNames = {'Var1', 'Var2', 'Var3', 'Var4', 'Var5', 'Var6', 'Var7', 'Var8', 'Var9', 'Var10', 'Var11', 'Var12', 'Var13', 'Var14', 'Var15', 'Var16', 'Var17', 'Var18', 'Var19', 'Var20', 'Var21', 'Var22', 'Var23', 'Var24', 'Var25', 'Var26', 'Var27', 'Var28', 'Var29', 'Var30', 'Var31', 'Var32', 'Var33', 'Var34', 'Var35', 'Var36', 'Var37', 'Var38', 'Var39', 'Var40', 'Var41', 'Var42', 'Var43', 'Var44', 'Var45', 'Var46', 'Var47', 'Var48', 'Var49', 'Var50', 'Var51', 'Var52', 'Var53', 'Var54', 'Var55', 'Var56', 'Var57', 'Var58', 'Var59', 'Var60', 'Var61', 'Var62', 'Var63', 'Var64', 'Var65', 'Var66', 'Var67', 'Var68', 'Var69', 'Var70', 'Var71', 'Var72', 'Var73', 'Var74', 'Var75', 'Var76', 'Var77', 'Var78', 'Var79', 'Var80', 'Var81', 'Var82', 'Var83', 'Var84', 'Var85', 'Var86', 'Var87', 'Var88', 'Var89', 'Var90', 'Var91', 'Var92', 'Var93', 'Var94', 'Var95', 'Var96', 'Var97', 'Var98', 'Var99', 'Var100', 'Var101', 'Var102', 'Var103', 'Var104', 'Var105', 'Var106', 'Var107', 'Var108', 'Var109', 'Var110', 'T'};
            predictors = inputTable(:, predictorNames);
            response = inputTable.Label;
            isCategoricalPredictor = [false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false];
            classNames = categorical({'ANDRAS'; 'DANIEL'; 'GLENN'; 'MARIA'; 'MITSUKO'; 'ROLAND'});
            
            % Perform cross-validation
            partitionedModel = crossval(trainedClassifier.ClassificationKNN, 'KFold', 5);
            
            % Compute validation predictions
            [validationPredictions, validationScores] = kfoldPredict(partitionedModel);
            
            % Compute validation accuracy
            validationAccuracy = 1 - kfoldLoss(partitionedModel, 'LossFun', 'ClassifError');        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.*', 'Please select a file to import');
            if (filename)
                app.tableBPM = readtable([filepath, filename]);
                app.UITable.Data = app.tableBPM; % need fix
                % 改成 Generate 之後放 midtempo
            end
            app.UITable.ColumnName = app.tableBPM.Properties.VariableNames;
            % app.UITable.RowName = app.tableBPM.ArtistName;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            addpath(app.tempogramToolboxPath);
            [artistNum, n] = size(app.tableBPM);
            artistDir = app.filepathEditField.Value;
            dataType = 'training_';

            for j=2:n
                Feature = [];
            
                for i=1:artistNum
                    % get BPM and artist data from input table
                    artistName1 = string(app.tableBPM{i, 1});
                    fileDir = strcat(artistDir, artistName1, '\');
                    fullFilePath = strcat(fileDir, app.tableBPM.Properties.VariableNames{j}, '.wav');
            
                    % generate feature table of each artist (same song) and concatenate them
                    Feature_each_artist = fperformanceAnoSplit(app, fullFilePath, artistName1, app.batch_size);
                    app.tableBPM{i, j} = app.midBPM;
                    
                    % concat training and testing data
                    Feature = [Feature; Feature_each_artist];
                end
            
                % generate output .csv file, for each song
                trainingFilename = strcat(dataType, app.tableBPM.Properties.VariableNames{j}, '.csv'); % can be modified
                trainingFilePath = strcat(artistDir, trainingFilename);
                writetable(Feature, trainingFilePath);
            end

            app.UITable.Data = app.tableBPM; % 顯示BPM在app的table上
            msgbox('data has created!');
        end

        % Button pushed function: TrainingDataButton
        function TrainingDataButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.*', 'Please select a file to import');
            if (filename) 
                app.trainT = readtable([filepath, filename]);
                app.WaitingLamp.Color = [1, 1, 0];
                app.DescriptionTextArea.Value = '';
            end
        end

        % Button pushed function: TestingDataButton
        function TestingDataButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.*', 'Please select a file to import');
            if (filename)
                app.testT = readtable([filepath, filename]);
                app.WaitingLamp.Color = [1, 1, 0];
                app.DescriptionTextArea.Value = '';
                
                r = size(app.testT, 1); % row num of testT
                % if testing data's label not in training set, change it to 'ANDRAS'
                if ~ismember(app.testT.Label{1}, app.labels)
                    for i=1:r
                        app.testT.Label{i} = app.labels{1};   
                    end
                end
            end
        end

        % Button pushed function: trainandpredictButton
        function trainandpredictButtonPushed(app, event)
            [trainedModel, validationAccuracy] = autoKNNnolimit(app, app.trainT);
            [yfit,scores] = trainedModel.predictFcn(app.testT);
            YTest = table2array(app.testT(:, 'Label'));
            YTest = categorical(YTest);
            cm = confusionmat(YTest, yfit, 'Order', app.labels);
            predictNum = sum(cm);
            [~, I] = max(predictNum);

            % testAccuracy = trace(cm) / numel(YTest);
            
            % if 被預測最多的一類 > 所有samples的80%
            if predictNum(I) > 0.8 * sum(predictNum)
                app.WatingLabel.Text = 'Not Original';
                app.WaitingLamp.Color = [1, 0, 0];
                app.DescriptionTextArea.Value = sprintf('The input audio file is recognized as a version played by musician %s in the database.', app.labels{I});
            else
                app.WatingLabel.Text = 'Original';
                app.WaitingLamp.Color = [0, 1, 0];
                app.DescriptionTextArea.Value = 'The classifier did not categorize the input audio file into a specific musician.';
            end
            
            app.UITable2.Data = cm;
            app.UITable2.ColumnName = app.labels;
            app.UITable2.RowName = app.labels;
            % confusionMatrixChart = confusionchart(cm, {'ANDRAS'; 'DANIEL'; 'GLENN'; 'MARIA'; 'MITSUKO'; 'ROLAND'});
            
        end

        % Value changed function: filepathEditField
        function filepathEditFieldValueChanged(app, event)
            value = app.filepathEditField.Value;
            
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            [filename, filepath] = uigetfile('*.*', 'Please select a file to import');
            if (filename)
                app.test_fullfilepath = [filepath, filename];
                % app.tableBPM = readtable([filepath, filename]);
                % app.UITable.Data = app.tableBPM;
            end
            % app.UITable.ColumnName = app.tableBPM.Properties.VariableNames;
        end

        % Button pushed function: GenerateButton_2
        function GenerateButton_2Pushed(app, event)
            addpath(app.tempogramToolboxPath);
            artistDir = app.filepathEditField.Value;
            dataType = 'testing';

            fullFilePath = app.test_fullfilepath; % "...\ANDRAS\Mv1.wav"
            splitstr = split(fullFilePath, '\');
            artistName1 = splitstr(end-1); % need fix
    
            % generate feature table of each artist (same song) and concatenate them
            Feature = fperformanceAnoSplit(app, fullFilePath, artistName1, app.batch_size);
            
            % generate output .csv file, for each song
            trainingFilename = strcat(dataType, '.csv'); % can be fix
            trainingFilePath = strcat(artistDir, trainingFilename);
            writetable(Feature, trainingFilePath);

            msgbox('data has created!');
        end

        % Value changing function: filepathEditField
        function filepathEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CopyrightDetector = uifigure('Visible', 'off');
            app.CopyrightDetector.Name = 'Copyright Detector';
            app.TabGroup = uitabgroup(app.CopyrightDetector);
            app.DataPreparationTab = uitab(app.TabGroup);
            app.DataPreparationTab.Title = 'Data Preparation';
            app.AudioLibraryPanel = uipanel(app.DataPreparationTab);
            app.AudioLibraryPanel.Title = 'Audio Library';
            app.LoadButton = uibutton(app.AudioLibraryPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.GenerateButton = uibutton(app.AudioLibraryPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.DataViewerPanel = uipanel(app.DataPreparationTab);
            app.DataViewerPanel.Title = 'Data Viewer';
            app.UITable = uitable(app.DataViewerPanel);
            app.UITable.ColumnName = {'Artist Name'; 'Song1'; 'Song2'; 'Song3'};
            app.UITable.RowName = {};
            app.filepathEditFieldLabel = uilabel(app.DataViewerPanel);
            app.filepathEditFieldLabel.Text = 'file path';
            app.filepathEditField = uieditfield(app.DataViewerPanel, 'text');
            app.filepathEditField.ValueChangedFcn = createCallbackFcn(app, @filepathEditFieldValueChanged, true);
            app.filepathEditField.ValueChangingFcn = createCallbackFcn(app, @filepathEditFieldValueChanging, true);
            app.filepathEditField.Value = 'C:\Users\paowen\Documents\111-2\Piano_Comparison\code\AppTest\AudioLibrary_KV279\';
            app.TestingExcerptPanel = uipanel(app.DataPreparationTab);
            app.TestingExcerptPanel.Title = 'Testing Excerpt';
            app.GenerateButton_2 = uibutton(app.TestingExcerptPanel, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_2Pushed, true);
            app.GenerateButton_2.Text = 'Generate';
            app.LoadButton_2 = uibutton(app.TestingExcerptPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.TestingTab = uitab(app.TabGroup);
            app.TestingTab.Title = 'Testing';
            app.LoadDataPanel = uipanel(app.TestingTab);
            app.LoadDataPanel.Title = 'Load Data';
            app.TestingDataButton = uibutton(app.LoadDataPanel, 'push');
            app.TestingDataButton.ButtonPushedFcn = createCallbackFcn(app, @TestingDataButtonPushed, true);
            app.TestingDataButton.Text = 'Testing Data';
            app.TrainingDataButton = uibutton(app.LoadDataPanel, 'push');
            app.TrainingDataButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingDataButtonPushed, true);
            app.TrainingDataButton.Text = 'Training Data';
            app.StartPanel = uipanel(app.TestingTab);
            app.StartPanel.Title = 'Start';
            app.trainandpredictButton = uibutton(app.StartPanel, 'push');
            app.trainandpredictButton.ButtonPushedFcn = createCallbackFcn(app, @trainandpredictButtonPushed, true);
            app.trainandpredictButton.Text = 'train and predict';
            app.ResultPanel = uipanel(app.TestingTab);
            app.ResultPanel.Title = 'Result';
            app.WaitingLamp = uilamp(app.ResultPanel);
            app.WatingLabel = uilabel(app.ResultPanel);
            app.WatingLabel.Text = 'Wating';
            app.DescriptionTextAreaLabel = uilabel(app.ResultPanel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.ResultPanel);
            app.ConfusionMatrixPanel = uipanel(app.TestingTab);
            app.ConfusionMatrixPanel.Title = 'Confusion Matrix';
            app.UITable2 = uitable(app.ConfusionMatrixPanel);
            app.UITable2.ColumnName = {'Artist 1'; 'Artist2'; 'Artist 3'; 'Artist 4'; 'Artist 5'; 'Artist 6'};
            app.UITable2.RowName = {};
            app.PredictedClassLabel = uilabel(app.ConfusionMatrixPanel);
            app.PredictedClassLabel.Text = 'Predicted Class';
            app.TrueClassLabel = uilabel(app.ConfusionMatrixPanel);
            app.TrueClassLabel.Text = 'True Class';
            % Show the figure after all components are created
            app.CopyrightDetector.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CopyrightDetector)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CopyrightDetector)
        end
    end
end